classdef thermoscan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GenerateReportButton         matlab.ui.control.Button
        ScanOptionGroupButtonGroup   matlab.ui.container.ButtonGroup
        ScanDoctorAppointmentButton  matlab.ui.control.RadioButton
        ScanOnlyButton               matlab.ui.control.RadioButton
        SlotDropDown                 matlab.ui.control.DropDown
        SlotDropDownLabel            matlab.ui.control.Label
        DoctorDropDown               matlab.ui.control.DropDown
        DoctorDropDownLabel          matlab.ui.control.Label
        SymptomsTextArea             matlab.ui.control.TextArea
        SymptomsTextAreaLabel        matlab.ui.control.Label
        ConditionsTextArea           matlab.ui.control.TextArea
        ConditionsTextAreaLabel      matlab.ui.control.Label
        HistoryTextArea              matlab.ui.control.TextArea
        HistoryTextAreaLabel         matlab.ui.control.Label
        WeightEditField              matlab.ui.control.NumericEditField
        WeightEditFieldLabel         matlab.ui.control.Label
        AgeEditField                 matlab.ui.control.NumericEditField
        AgeEditFieldLabel            matlab.ui.control.Label
        NameEditField                matlab.ui.control.EditField
        NameEditFieldLabel           matlab.ui.control.Label
        LoginButton                  matlab.ui.control.Button
        PatientIDEditField           matlab.ui.control.EditField
        PatientIDEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
     
    patients table           % main patient table loaded from CSV
    pidIndex containers.Map  % fast lookup: PatientID -> row index
    currentPID string        % currently logged-in patient ID
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
          % Button pushed function: LoginButton

    % Load patient data
    data = readtable('Diagnostics.csv');

    % Get entered PatientID
    enteredID = str2double(app.PatientIDEditField.Value);

    % Find matching row
    idx = find(data.PatientID == enteredID, 1);

    if ~isempty(idx)
        % Assign values to GUI fields
        app.NameEditField.Value   = strcat("Patient_", num2str(enteredID)); % Dummy name
        app.AgeEditField.Value    = data.Age(idx);
        app.WeightEditField.Value = data.Weight(idx);
    else
        uialert(app.UIFigure, 'Invalid Patient ID', 'Login Error');
        return; % stop here if invalid
    end

    % Dummy risk score (later link with symptoms)
    riskScore = rand(); % random between 0–1

    % Assign doctor based on risk score
    if riskScore > 0.7
        app.DoctorDropDown.Items = {'Dr. Smith (Oncologist)', 'Dr. Lee (Radiologist)'};
    else
        app.DoctorDropDown.Items = {'Dr. Patel (General Physician)', 'Dr. Kumar (Specialist)'};
    end

    % Assign dummy time slots
    app.SlotDropDown.Items = {'10:00 AM', '11:30 AM', '02:00 PM', '04:00 PM'};
    app.SlotDropDown.Value = app.SlotDropDown.Items{1};  % Default to first
end


        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
          
    % File name for PDF
    filename = fullfile(pwd, 'PatientReport.pdf');

    % Open file
    fid = fopen(filename, 'w');

    % Write Patient Details
    fprintf(fid, '--- Patient Diagnostic Report ---\n\n');
    fprintf(fid, 'Patient ID: %s\n', app.PatientIDEditField.Value);
    fprintf(fid, 'Name: %s\n', app.NameEditField.Value);
    fprintf(fid, 'Age: %d\n', app.AgeEditField.Value);
    fprintf(fid, 'Weight: %.1f\n\n', app.WeightEditField.Value);

    % History and Symptoms
    fprintf(fid, 'Medical History:\n%s\n\n', app.HistoryTextArea.Value);
    fprintf(fid, 'Symptoms:\n%s\n\n', app.SymptomsTextArea.Value);
    fprintf(fid, 'Conditions:\n%s\n\n', app.ConditionsTextArea.Value);

    % Appointment info (only if chosen)
    if strcmp(app.ScanOptionGroup.SelectedObject.Text, 'Scan + Doctor Appointment')
        fprintf(fid, 'Assigned Doctor: %s\n', app.DoctorDropDown.Value);
        fprintf(fid, 'Appointment Slot: %s\n\n', app.SlotDropDown.Value);
    else
        fprintf(fid, 'Scan Option: Scan Only (No Doctor Appointment)\n\n');
    end

    % Closing line
    fprintf(fid, '--- End of Report ---\n');

    % Close file
    fclose(fid);

    % Show confirmation
    uialert(app.UIFigure, 'Patient Report saved as PatientReport.pdf', 'Report Generated');
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.PatientIDEditFieldLabel.Text = 'PatientIDEditField';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'LoginButton';
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Enable = 'off';
            app.NameEditFieldLabel.Text = 'NameEditField';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Enable = 'off';
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Enable = 'off';
            app.AgeEditFieldLabel.Text = 'AgeEditField';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditField.Enable = 'off';
            app.WeightEditFieldLabel = uilabel(app.UIFigure);
            app.WeightEditFieldLabel.Enable = 'off';
            app.WeightEditFieldLabel.Text = 'WeightEditField';
            app.WeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeightEditField.Enable = 'off';
            app.HistoryTextAreaLabel = uilabel(app.UIFigure);
            app.HistoryTextAreaLabel.Text = 'HistoryTextArea';
            app.HistoryTextArea = uitextarea(app.UIFigure);
            app.ConditionsTextAreaLabel = uilabel(app.UIFigure);
            app.ConditionsTextAreaLabel.Text = 'ConditionsTextArea';
            app.ConditionsTextArea = uitextarea(app.UIFigure);
            app.SymptomsTextAreaLabel = uilabel(app.UIFigure);
            app.SymptomsTextAreaLabel.Text = 'SymptomsTextArea';
            app.SymptomsTextArea = uitextarea(app.UIFigure);
            app.DoctorDropDownLabel = uilabel(app.UIFigure);
            app.DoctorDropDownLabel.Text = 'DoctorDropDown';
            app.DoctorDropDown = uidropdown(app.UIFigure);
            app.SlotDropDownLabel = uilabel(app.UIFigure);
            app.SlotDropDownLabel.Text = 'SlotDropDown';
            app.SlotDropDown = uidropdown(app.UIFigure);
            app.ScanOptionGroupButtonGroup = uibuttongroup(app.UIFigure);
            app.ScanOptionGroupButtonGroup.Title = 'ScanOptionGroup';
            app.ScanOnlyButton = uiradiobutton(app.ScanOptionGroupButtonGroup);
            app.ScanOnlyButton.Text = 'Scan Only';
            app.ScanOnlyButton.Value = true;
            app.ScanDoctorAppointmentButton = uiradiobutton(app.ScanOptionGroupButtonGroup);
            app.ScanDoctorAppointmentButton.Text = 'Scan + Doctor Appointment';
            app.GenerateReportButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'GenerateReportButton';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thermoscan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end