classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ResultLabel                matlab.ui.control.Label
        ScheduleAppointmentButton  matlab.ui.control.Button
        ViewScanButton             matlab.ui.control.Button
        SymptomsLabel              matlab.ui.control.Label
        SwellingCheckBox           matlab.ui.control.CheckBox
        SkinChangeCheckBox         matlab.ui.control.CheckBox
        FatigueCheckBox            matlab.ui.control.CheckBox
        PainCheckBox               matlab.ui.control.CheckBox
        LumpCheckBox               matlab.ui.control.CheckBox
        AgeLabel                   matlab.ui.control.Label
        NameLabel                  matlab.ui.control.Label
        LoadPatientDataButton      matlab.ui.control.Button
        PatientIDEditField         matlab.ui.control.EditField
        PatientIDFieldLabel        matlab.ui.control.Label
        SmartThermoscanAppLabel    matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         basePath = "C:\Users\Harini\OneDrive\Desktop\Smart Thermoscan\";  % main project folder
        metadata   % will hold Diagnostics.csv data
        selectedPatient  % will hold one patient’s data row
    end
    
    methods (Access = private)
        
       % ------------------------------
% Helper functions (paste here)
% ------------------------------

function val = safeGet(~, row, col, def)
    % Safely read a numeric value from a table row (use default if missing)
    if ismember(col, row.Properties.VariableNames)
        v = row.(col);
        if iscell(v), v = v{1}; end
        val = double(v);
        if isnan(val), val = def; end
    else
        val = def;
    end
end

function features = extract_features(~, img)
    % Split image into 6 zones and compute [max, mean, std] per zone
    if ndims(img) == 3, img = rgb2gray(img); end
    [h,w] = size(img);
    h1 = round(h/3); w1 = round(w/2);
    Z1 = img(1:h1,      1:w1);      % upper-left
    Z2 = img(1:h1,      w1+1:end);  % upper-right
    Z3 = img(h1+1:2*h1, 1:w1);      % middle-left
    Z4 = img(h1+1:2*h1, w1+1:end);  % middle-right
    Z5 = img(2*h1+1:end,1:w1);      % lower-left
    Z6 = img(2*h1+1:end,w1+1:end);  % lower-right
    zones = {Z1,Z2,Z3,Z4,Z5,Z6};
    f = @(z) [ double(max(z(:))), double(mean(z(:))), double(std(double(z(:)))) ];
    features = zeros(1, 18);
    for k = 1:6
        features( (k-1)*3 + (1:3) ) = f(zones{k});
    end
end

function urgency = biopsy_urgency(~, features, temp, age, weight, height, symptoms)
    % Simple heuristic risk scoring and category mapping
    if nargin < 7, symptoms = zeros(1,5); end
    if isempty(temp) || isnan(temp), temp = 36.5; end
    if isempty(age)  || isnan(age),  age = 35;    end
    if isempty(weight)|| isnan(weight), weight = 60; end
    if isempty(height)|| isnan(height), height = 160; end

    bmi = weight / ((height/100)^2);
    % intensity features: max per zone and mean per zone
    zoneMax = features(1:3:end);
    zoneMean = features(2:3:end);

    maxI = max(zoneMax);
    meanI = mean(zoneMean);
    sympScore = sum(symptoms) / 5; % normalize 0..1

    % normalized components (ensure scalar)
    asymNorm = min(1, (maxI - meanI) / 60); % relative hotspot measure
    maxNorm  = min(1, max(0,(maxI - 100)/150)); % map roughly 100..250 -> 0..1
    tempNorm = min(1, max(0,(temp - 36)/3));
    ageNorm  = min(1, max(0,(age - 30)/30));
    bmiNorm  = min(1, max(0,(bmi - 20)/20));

    % weighted risk (tweak weights as needed)
    riskScore = 0.35*maxNorm + 0.25*asymNorm + 0.15*tempNorm + 0.15*ageNorm + 0.1*sympScore;
    riskScore = double(riskScore);  % scalar 0..1

    % map to categories
    if riskScore >= 0.70
        urgency = "High";
    elseif riskScore >= 0.40
        urgency = "Medium";
    else
        urgency = "Low";
    end
end

function doc = assign_doctor(~, urgency)
    % Basic assignment based on urgency
    onc = 'Dr. Meera (Oncologist)';
    rad = 'Dr. Ramesh (Radiologist)';
    surg = 'Dr. Kavya (Surgeon)';
    spec = 'Dr. Arjun (Thermal Imaging)';
    switch string(urgency)
        case "High"
            doc = onc;
        case "Medium"
            doc = rad;
        otherwise
            % random pick between surgeon and specialist
            arr = {surg, spec};
            doc = arr{randi(2)};
    end
end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPatientDataButton
        function LoadPatientDataButtonPushed(app, event)
            csvFile = fullfile(app.basePath, "csv", "Diagnostics.csv");

    if isempty(app.metadata)
        if isfile(csvFile)
            app.metadata = readtable(csvFile, 'VariableNamingRule','preserve');
        else
            uialert(app.UIFigure, "Diagnostics.csv not found!", "Error");
            return;
        end
    end

    patientID = app.PatientIDField.Value;
    if isempty(patientID)
        uialert(app.UIFigure, "Enter a valid Patient ID", "Error");
        return;
    end

    row = app.metadata(strcmp(app.metadata.("Image"), patientID), :);
    if isempty(row)
        uialert(app.UIFigure, sprintf("Patient %s not found!", patientID), "Error");
        return;
    end

    app.selectedPatient = row;

    % Display info
    age = row.("Age(years)");
    nameStr = sprintf("Patient ID: %s", patientID);
    app.NameLabel.Text = nameStr;
    app.AgeLabel.Text = sprintf("Age: %d", age);

    uialert(app.UIFigure, "Patient data loaded successfully!", "Success");
        end

        % Button pushed function: ViewScanButton
        function ViewScanButtonPushed(app, event)
            if isempty(app.selectedPatient)
        uialert(app.UIFigure, "Please load a patient first.", "Error");
        return;
    end

    row = app.selectedPatient;
    patientID = row.("Image");
    temp = row.("Temp(°C)");
    age = row.("Age(years)");
    weight = row.("Weight (Kg)");
    height = row.("Height(cm)");
    leftLabel = row.("Left"){1};
    rightLabel = row.("Right"){1};

    if strcmpi(leftLabel,"Abnormal")
        scanType = "left";
    elseif strcmpi(rightLabel,"Abnormal")
        scanType = "right";
    else
        scanType = "anterior";
    end

    imgFolder = fullfile(app.basePath, "data", "raw_images", patientID);
    imgPattern = fullfile(imgFolder, sprintf("%s*%s*", patientID, scanType));
    fileList = dir(imgPattern);
    if isempty(fileList)
        fileList = dir(fullfile(imgFolder, sprintf("%s*", patientID)));
    end
    if isempty(fileList)
        uialert(app.UIFigure, "No image found for this patient.", "Error");
        return;
    end

    img = imread(fullfile(fileList(1).folder, fileList(1).name));
    if size(img,3) == 3
        img = rgb2gray(img);
    end
    img = imresize(img, [240, 320]);

    % Display original
    imshow(img, 'Parent', app.UIAxes);
    title(app.UIAxes, sprintf("Original - %s (%s)", patientID, scanType));

    % Create heatmap overlay
    imgNorm = mat2gray(img);
    heatRGB = ind2rgb(gray2ind(imgNorm, 256), hot(256));
    imshow(img, 'Parent', app.UIAxes2);
    hold(app.UIAxes2, 'on');
    hOverlay = imshow(heatRGB, 'Parent', app.UIAxes2);
    alpha(hOverlay, 0.5);
    title(app.UIAxes2, 'Thermal Heatmap Overlay');

    % Read checkbox values (symptoms)
    symptoms = [app.LumpCheck.Value, app.PainCheck.Value, ...
                app.SwellCheck.Value, app.SkinCheck.Value, ...
                app.FatigueCheck.Value];

    % Extract features and predict urgency
    features = app.extract_features(img);
    urgency = app.biopsy_urgency(features, temp, age, weight, height, symptoms);

    app.ResultLabel.Text = sprintf("Predicted Biopsy Urgency: %s", urgency);
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SmartThermoscanAppLabel = uilabel(app.UIFigure);
            app.SmartThermoscanAppLabel.Text = 'Smart Thermoscan App';
            app.PatientIDFieldLabel = uilabel(app.UIFigure);
            app.PatientIDFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'text');
            app.LoadPatientDataButton = uibutton(app.UIFigure, 'push');
            app.LoadPatientDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPatientDataButtonPushed, true);
            app.LoadPatientDataButton.Text = 'Load Patient Data';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name: ';
            app.AgeLabel = uilabel(app.UIFigure);
            app.AgeLabel.Text = 'Age: ';
            app.LumpCheckBox = uicheckbox(app.UIFigure);
            app.LumpCheckBox.Text = 'Lump';
            app.PainCheckBox = uicheckbox(app.UIFigure);
            app.PainCheckBox.Text = 'Pain';
            app.FatigueCheckBox = uicheckbox(app.UIFigure);
            app.FatigueCheckBox.Text = 'Fatigue';
            app.SkinChangeCheckBox = uicheckbox(app.UIFigure);
            app.SkinChangeCheckBox.Text = 'Skin Change';
            app.SwellingCheckBox = uicheckbox(app.UIFigure);
            app.SwellingCheckBox.Text = 'Swelling';
            app.SymptomsLabel = uilabel(app.UIFigure);
            app.SymptomsLabel.Text = 'Symptoms: ';
            app.ViewScanButton = uibutton(app.UIFigure, 'push');
            app.ViewScanButton.ButtonPushedFcn = createCallbackFcn(app, @ViewScanButtonPushed, true);
            app.ViewScanButton.Text = 'View Scan';
            app.ScheduleAppointmentButton = uibutton(app.UIFigure, 'push');
            app.ScheduleAppointmentButton.Text = 'Schedule Appointment';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end