classdef app1backup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PatientIDEditField         matlab.ui.control.EditField
        PatientIDEditFieldLabel    matlab.ui.control.Label
        ResultLabel                matlab.ui.control.Label
        ScheduleAppointmentButton  matlab.ui.control.Button
        ViewScanButton             matlab.ui.control.Button
        SymptomsLabel              matlab.ui.control.Label
        SwellingCheckBox           matlab.ui.control.CheckBox
        SkinChangeCheckBox         matlab.ui.control.CheckBox
        FatigueCheckBox            matlab.ui.control.CheckBox
        PainCheckBox               matlab.ui.control.CheckBox
        LumpCheckBox               matlab.ui.control.CheckBox
        AgeLabel                   matlab.ui.control.Label
        NameLabel                  matlab.ui.control.Label
        LoadPatientDataButton      matlab.ui.control.Button
        SmartThermoscanAppLabel    matlab.ui.control.Label
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         basePath = "C:\Users\Harini\OneDrive\Desktop\Smart Thermoscan\";  % update if needed
        metadata    % table loaded from Diagnostics.csv
        selectedPatient  % row for currently selected patient
        imgGray     % last loaded image    
    end
    
    methods (Access = private)
        
        % ------------------------------
% Your custom private functions
% ------------------------------
function val = safeGet(app, row, col, def)
    if ismember(col, row.Properties.VariableNames)
        v = row.(col);
        if iscell(v), v = v{1}; end
        val = double(v);
        if isnan(val), val = def; end
    else
        val = def;
    end
end

function features = extract_features(app, img)
    [h,w] = size(img);
    h1 = round(h/3); w1 = round(w/2);
    Z = {img(1:h1,1:w1), img(1:h1,w1+1:end), ...
         img(h1+1:2*h1,1:w1), img(h1+1:2*h1,w1+1:end), ...
         img(2*h1+1:end,1:w1), img(2*h1+1:end,w1+1:end)};
    f = @(z)[max(z(:)), mean(z(:)), std(double(z(:)))];
    features = [];
    for i = 1:6
        features = [features, f(Z{i})];
    end
end

function urgency = biopsy_urgency(app, f, temp, age, weight, height, symptoms)
    bmi = weight / ((height/100)^2);
    maxI = max(f(1:3:end));
    meanI = mean(f(2:3:end));
    sympScore = sum(symptoms)/5;
    asymNorm = min(1, (maxI - meanI) / 60);
    maxNorm  = min(1, max(0,(maxI - 100)/150));
    tempNorm = min(1, max(0,(temp - 36)/3));
    ageNorm  = min(1, max(0,(age - 30)/30));
    riskScore = 0.35*maxNorm + 0.25*asymNorm + 0.15*tempNorm + 0.15*ageNorm + 0.1*sympScore;
    if riskScore >= 0.70
        urgency = "High";
    elseif riskScore >= 0.40
        urgency = "Medium";
    else
        urgency = "Low";
    end
end

function doc = assign_doctor(app, urgency)
    doctors = { 'Dr. Meera (Oncologist)', 'Dr. Ramesh (Radiologist)', ...
                'Dr. Kavya (Surgeon)', 'Dr. Arjun (Thermal Imaging)' };
    switch string(urgency)
        case "High", doc = doctors{1};
        case "Medium", doc = doctors{2};
        otherwise, doc = doctors{2 + randi(2)};
    end
end

    end

%-----------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadPatientDataButton
        function LoadPatientDataButtonPushed2(app, event)
          csvFile = fullfile(app.basePath, "csv", "Diagnostics.csv");
    if isempty(app.metadata)
        if isfile(csvFile)
            app.metadata = readtable(csvFile,'VariableNamingRule','preserve');
        else
            uialert(app.UIFigure, "Diagnostics.csv not found!", "Error");
            return;
        end
    end

    pid = string(app.PatientIDEditField.Value);
    if strlength(pid) == 0
        uialert(app.UIFigure, "Enter Patient ID", "Error");
        return;
    end

    idx = find(strcmp(app.metadata.("Image"), pid), 1);
    if isempty(idx)
        uialert(app.UIFigure, sprintf("Patient %s not found!", pid), "Error");
        return;
    end

    app.selectedPatient = app.metadata(idx,:);
    app.NameLabel.Text = "Patient ID: " + pid;

    if ismember("Age(years)", app.selectedPatient.Properties.VariableNames)
        app.AgeLabel.Text = "Age: " + string(app.selectedPatient.("Age(years)"));
    end

    app.ResultLabel.Text = "Patient data loaded successfully ✅";

        end

        % Button pushed function: ViewScanButton
        function ViewScanButtonPushed(app, event)
            if isempty(app.selectedPatient)
        uialert(app.UIFigure, "Please load a patient first.", "Error");
        return;
    end

    row = app.selectedPatient;
    patientID = string(row.("Image"));
    leftLabel = row.("Left"){1};
    rightLabel = row.("Right"){1};

    if strcmpi(leftLabel,"Abnormal")
        scanType = "left";
    elseif strcmpi(rightLabel,"Abnormal")
        scanType = "right";
    else
        scanType = "anterior";
    end

    imgFolder = fullfile(app.basePath, "data", "raw_images", patientID);
    files = dir(fullfile(imgFolder, sprintf("%s*%s*", patientID, scanType)));
    if isempty(files)
        files = dir(fullfile(imgFolder, sprintf("%s*", patientID)));
    end
    if isempty(files)
        uialert(app.UIFigure, "No image found for this patient.", "Error");
        return;
    end

    img = imread(fullfile(files(1).folder, files(1).name));
    if ndims(img) == 3
        img = rgb2gray(img);
    end
    img = imresize(img, [240, 320]);
    app.imgGray = img;

    % Display original image
    imshow(img, 'Parent', app.UIAxes);
    title(app.UIAxes, sprintf("Original - %s", patientID));

    % Heatmap overlay in second axes
    imgNorm = mat2gray(img);
    heatRGB = ind2rgb(gray2ind(imgNorm, 256), hot(256));
    imshow(img, 'Parent', app.UIAxes_2);
    hold(app.UIAxes_2, 'on');
    hOverlay = imshow(heatRGB, 'Parent', app.UIAxes_2);
    alpha(hOverlay, 0.5);
    title(app.UIAxes_2, 'Thermal Heatmap');

    % read required patient fields safely
    % read required patient fields correctly
temp   = double(row.("Temp(¡C)"));    % note the column name exactly
age    = double(row.("Age(years)"));
weight = double(row.("Weight (Kg)"));
height = double(row.("Height(cm)"));

    % read checkboxes (symptoms)
    symptoms = [app.LumpCheckBox.Value, app.PainCheckBox.Value, ...
                app.SwellingCheckBox.Value, app.SkinChangeCheckBox.Value, ...
                app.FatigueCheckBox.Value];

    feats = extract_features(app, img);
    urgency = biopsy_urgency(app, feats, temp, age, weight, height, symptoms);

    app.ResultLabel.Text = sprintf("Predicted Biopsy Urgency: %s", urgency);
        end

        % Button pushed function: ScheduleAppointmentButton
        function ScheduleAppointmentButtonPushed2(app, event)
           txt = app.ResultLabel.Text;

% Check if the scan has been viewed
if isempty(txt) || contains(txt, "Please view scan")
    uialert(app.UIFigure, "Please view scan first.", "Error");
    return;
end

% Directly assign doctor and slot (no urgency text used)
doctor = assign_doctor(app, "normal");  % You can keep urgency as "normal" or any default
timeSlots = ["10:00 AM", "2:30 PM", "5:00 PM"];
slot = timeSlots(randi(numel(timeSlots)));

% Update result label without biopsy urgency
app.ResultLabel.Text = sprintf("Appointment scheduled with %s at %s", doctor, slot);

% Show alert message
uialert(app.UIFigure, sprintf("Appointment scheduled with %s at %s", doctor, slot), "Success");

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SmartThermoscanAppLabel = uilabel(app.UIFigure);
            app.SmartThermoscanAppLabel.Text = 'Smart Thermoscan App';
            app.LoadPatientDataButton = uibutton(app.UIFigure, 'push');
            app.LoadPatientDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPatientDataButtonPushed2, true);
            app.LoadPatientDataButton.Text = 'Load Patient Data';
            app.NameLabel = uilabel(app.UIFigure);
            app.NameLabel.Text = 'Name:                    ';
            app.AgeLabel = uilabel(app.UIFigure);
            app.AgeLabel.Text = 'Age: ';
            app.LumpCheckBox = uicheckbox(app.UIFigure);
            app.LumpCheckBox.Text = 'Lump';
            app.PainCheckBox = uicheckbox(app.UIFigure);
            app.PainCheckBox.Text = 'Pain';
            app.FatigueCheckBox = uicheckbox(app.UIFigure);
            app.FatigueCheckBox.Text = 'Fatigue';
            app.SkinChangeCheckBox = uicheckbox(app.UIFigure);
            app.SkinChangeCheckBox.Text = 'Skin Change';
            app.SwellingCheckBox = uicheckbox(app.UIFigure);
            app.SwellingCheckBox.Text = 'Swelling';
            app.SymptomsLabel = uilabel(app.UIFigure);
            app.SymptomsLabel.Text = 'Symptoms: ';
            app.ViewScanButton = uibutton(app.UIFigure, 'push');
            app.ViewScanButton.ButtonPushedFcn = createCallbackFcn(app, @ViewScanButtonPushed, true);
            app.ViewScanButton.Text = 'View Scan';
            app.ScheduleAppointmentButton = uibutton(app.UIFigure, 'push');
            app.ScheduleAppointmentButton.ButtonPushedFcn = createCallbackFcn(app, @ScheduleAppointmentButtonPushed2, true);
            app.ScheduleAppointmentButton.Text = 'Schedule Appointment';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.PatientIDEditFieldLabel = uilabel(app.UIFigure);
            app.PatientIDEditFieldLabel.Text = 'Patient ID';
            app.PatientIDEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1backup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end